classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResultValueLabel        matlab.ui.control.Label
        ResultLabel             matlab.ui.control.Label
        FilenameLabel           matlab.ui.control.Label
        CurrentTrieValueLabel   matlab.ui.control.Label
        CurrentTrieLabel        matlab.ui.control.Label
        MatchPanel              matlab.ui.container.Panel
        DigitPanel              matlab.ui.container.Panel
        PauseSpinner            matlab.ui.control.Spinner
        PausesSpinnerLabel      matlab.ui.control.Label
        MaskPanel               matlab.ui.container.Panel
        SelectAllButton         matlab.ui.control.Button
        DataDirectory           matlab.ui.control.DropDown
        StartButton             matlab.ui.control.Button
        TrainingSceneSelection  matlab.ui.control.ListBox
        ListBoxLabel            matlab.ui.control.Label
        DeluxeLabel             matlab.ui.control.Label
        HarmTomStreetSignDetectionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        TrainingPictures % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear workspace;
            startup;
            app.DataDirectoryValueChanged();
        end

        % Value changed function: DataDirectory
        function DataDirectoryValueChanged(app, event)
            value = app.DataDirectory.Value;
            app.TrainingPictures = TrainingPictureUtils.getPictures(convertCharsToStrings(value));

            for i = 1:length(app.TrainingPictures)
                items(i) = convertCharsToStrings(app.TrainingPictures(i).path);
            end

            app.TrainingSceneSelection.Items = items;
            app.SelectAllButtonPushed();
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.TrainingSceneSelection.Multiselect = 'on';
            app.TrainingSceneSelection.ItemsData = 1:length(app.TrainingSceneSelection.Items);
            app.TrainingSceneSelection.Value = app.TrainingSceneSelection.ItemsData;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Text = "Busy";
            app.StartButton.Enable = false;

            Run(app, app.TrainingPictures(app.TrainingSceneSelection.Value));

            app.StartButton.Text = "Start";
            app.StartButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HarmTomStreetSignDetectionLabel = uilabel(app.UIFigure);
            app.HarmTomStreetSignDetectionLabel.Text = 'Harm & Tom Street Sign Detection';
            app.DeluxeLabel = uilabel(app.UIFigure);
            app.DeluxeLabel.Text = 'Deluxe';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = '';
            app.TrainingSceneSelection = uilistbox(app.UIFigure);
            app.TrainingSceneSelection.Multiselect = 'on';
            app.TrainingSceneSelection.Value = {'Item 1'};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DataDirectory = uidropdown(app.UIFigure);
            app.DataDirectory.Items = {'TrainingPictures', 'TestPictures', 'TestPicturesSelected'};
            app.DataDirectory.ValueChangedFcn = createCallbackFcn(app, @DataDirectoryValueChanged, true);
            app.DataDirectory.Value = 'TrainingPictures';
            app.SelectAllButton = uibutton(app.UIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select all';
            app.MaskPanel = uipanel(app.UIFigure);
            app.MaskPanel.Title = 'Mask';
            app.PausesSpinnerLabel = uilabel(app.UIFigure);
            app.PausesSpinnerLabel.Text = 'Pause (s)';
            app.PauseSpinner = uispinner(app.UIFigure);
            app.DigitPanel = uipanel(app.UIFigure);
            app.DigitPanel.Title = 'Digits';
            app.MatchPanel = uipanel(app.UIFigure);
            app.MatchPanel.Title = 'Match';
            app.CurrentTrieLabel = uilabel(app.UIFigure);
            app.CurrentTrieLabel.Text = 'Current trie:';
            app.CurrentTrieValueLabel = uilabel(app.UIFigure);
            app.CurrentTrieValueLabel.Text = '0 km/h';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'Filename';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.ResultValueLabel = uilabel(app.UIFigure);
            app.ResultValueLabel.Text = '0 km/h';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end